classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Button          matlab.ui.control.Button
        Label_2         matlab.ui.control.Label
        ReceiveNewData  matlab.ui.control.TextArea
        Label_3         matlab.ui.control.Label
        ReceiveData     matlab.ui.control.TextArea
        Label_4         matlab.ui.control.Label
        SendData        matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
data=app.SendData.Value;
global s;
delete(instrfindall);%删除现在设置的串口
s=serial('com5');% 根据电脑进行选择com
set(s,'BaudRate',9600,'Parity','none','DataBits',8,'StopBits',1);
s.InputBufferSize=4096;
s.OutputBufferSize=4096;
s.BytesAvailableFcnMode = 'byte';%串口接收中断配置，此步配置中断触发模式是根据字节
s.BytesAvailableFcnCount = 8;%收入八个字符可触发中断。
fopen(s);
char1=num2str(data);
fwrite(s,char1,'uint8');
s.bytesAvailableFcn=@callback;

function callback(obj,~)
  c = fread(s, 8);
  if numel(app.ReceiveNewData.Value)>1
  data1=app.ReceiveNewData.Value;
  app.ReceiveData.Value=[app.ReceiveData.Value;data1];
  end
              app.ReceiveNewData.Value=char(c');
end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '发送';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '当前接收数据';
            app.ReceiveNewData = uitextarea(app.UIFigure);
            app.ReceiveNewData.Editable = 'off';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '历史接收数据';
            app.ReceiveData = uitextarea(app.UIFigure);
            app.ReceiveData.Editable = 'off';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '发送数据';
            app.SendData = uieditfield(app.UIFigure, 'numeric');
            app.SendData.ValueDisplayFormat = '%.0f';
            app.SendData.Value = 1.10010101111e+15;
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end